classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Knob         matlab.ui.control.Knob
        Switch       matlab.ui.control.ToggleSwitch
        Gauge        matlab.ui.control.Gauge
        Lamp         matlab.ui.control.Lamp
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_5      matlab.ui.control.Label
        EditField_1  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0; % Description
        m; % Description
    end
    
    methods (Access = private)
        
        function func(app)
              app.m = modbus('tcpip', '192.168.1.123', 80);
              write(app.m,'holdingregs',1,500)
              while(app.flag)
                k=read(app.m,'holdingregs',1,3,'uint16');
                app.Gauge.Value=k(1);
                app.EditField_1.Value=k(2);
                app.EditField_2.Value=k(3);
                pause(1)
              end
              clear m;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
         value = app.Switch.Value;
            if strcmp(value,'Вкл')
                app.Lamp.Color='g';
                app.flag = 1;
                func(app)
            else
                app.flag = 0;
                app.Lamp.Color='r';
            end
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value=app.Knob.Value;            
            write(app.m,'holdingregs',1,floor(value),'uint16')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Срединение'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 1000];
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Выкл', 'Вкл'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Выкл';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 1000];
            app.Knob.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Knob.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end